classdef BILP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField_5                     matlab.ui.control.EditField
        SaveFileLabel                   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        BILP_EBVButton                  matlab.ui.control.Button
        SelectEBVFileLabel              matlab.ui.control.Label
        SSLOResultsPanel                matlab.ui.container.Panel
        EditField_4                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        GapLabel                        matlab.ui.control.Label
        XflagLabel                      matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        OFVLabel                        matlab.ui.control.Label
        BILP_GradeButton                matlab.ui.control.Button
        SelectGradeFileLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SSLOLamp                        matlab.ui.control.Lamp
        NumberofBlockPerDirectionPanel  matlab.ui.container.Panel
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        StopeConfigurationPanel         matlab.ui.container.Panel
        MinPillarGamma2EditField        matlab.ui.control.NumericEditField
        MinPillarGamma2EditFieldLabel   matlab.ui.control.Label
        MinPillarGamma1EditField        matlab.ui.control.NumericEditField
        MinPillarGamma1EditFieldLabel   matlab.ui.control.Label
        MaxMiningBeta2EditField         matlab.ui.control.NumericEditField
        MaxMiningBeta2EditFieldLabel    matlab.ui.control.Label
        NumberofStopesEditField         matlab.ui.control.NumericEditField
        NumberofStopesEditFieldLabel    matlab.ui.control.Label
        MaxMiningBeta1EditField         matlab.ui.control.NumericEditField
        MaxMiningBeta1EditFieldLabel    matlab.ui.control.Label
        MinMiningAlpha2EditField        matlab.ui.control.NumericEditField
        MinMiningAlpha2EditFieldLabel   matlab.ui.control.Label
        MinMiningAlpha1EditField        matlab.ui.control.NumericEditField
        MinMiningAlpha1Label            matlab.ui.control.Label
        EBVGeneratorPanel               matlab.ui.container.Panel
        ProcessingCostEditField         matlab.ui.control.NumericEditField
        ProcessingCostLabel             matlab.ui.control.Label
        MiningCostEditField             matlab.ui.control.NumericEditField
        MiningCostEditFieldLabel        matlab.ui.control.Label
        CutoffgradegtEditField          matlab.ui.control.NumericEditField
        CutoffgradegtEditFieldLabel     matlab.ui.control.Label
        RecoveryEditField               matlab.ui.control.NumericEditField
        RecoveryLabel                   matlab.ui.control.Label
        RefineryCostEditField           matlab.ui.control.NumericEditField
        RefineryCostLabel               matlab.ui.control.Label
        PriceEditField                  matlab.ui.control.NumericEditField
        PriceLabel                      matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        SublevelStopeLayoutOptimizationSSLOLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PriceEditField.Value=1500;%                  % Selling Price of the mineral
            app.RefineryCostEditField.Value = 15;%           % Cost of selling + Refinery Cost 
            app.RecoveryEditField.Value=90;%                 % Recovery Matrix of the ore
            app.MiningCostEditField.Value=10;%               % Mining Cost per tonne of material
            app.ProcessingCostEditField.Value=5;             % Processing cost per tonne of ore
            %2000                                            % Block Tonnage            
            app.CutoffgradegtEditField.Value=3.5;              % Stope Cutoff grade
  
 
%% Geometrical and Geotechnical Parameter Initialization:
            app.MinMiningAlpha1EditField.Value = 3;        % Minimum Mining Width
            app.MinMiningAlpha2EditField.Value = 3;        % Minimum Mining Width
            app.MinPillarGamma1EditField.Value = 3;        % Minimum Pillar Width
            app.MinPillarGamma2EditField.Value = 3;            % Minimum Pillar Width
            app.NumberofStopesEditField.Value = 4;             % Number of stopes
            app.MaxMiningBeta1EditField.Value = 5; %max mining
            app.MaxMiningBeta2EditField.Value = 5;%MAX MINING

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            %ts = tic;
%% Read Data
% Para = xlsread(inputfile,'Cost_data', 'C6:C30');
%infile = app.Label.Text;
%  F = readmatrix(infile,'Sheet','Block_Model','Range','Q4:AJ43');%'data5.xlsx');
%F = readmatrix(inputfile,'Sheet','Block_Model','Range','N5:N679');%'data5.xlsx');
%F = readmatrix(inputfile,'Sheet','Block_Model','Range','N5:N1759');%'data5.xlsx');
%% Optimization Parameter Initialization:
 

%%Optimization Parameter Initialization:
    Price = app.PriceEditField.Value/31.1035;%           Para(2)/31.1035;        % Selling Price of the mineral
     Cref = app.RefineryCostEditField.Value;%Para(4);                % Cost of selling + Refinery Cost 
      Rec = app.RecoveryEditField.Value;%Para(10);               % Recovery Matrix of the ore
     Cmin = app.MiningCostEditField.Value;%Para(6);                % Mining Cost per tonne of material
     Cpro = app.ProcessingCostEditField.Value;%Para(8);                % Processing cost per tonne of ore
   BlkTon = 6750;%Para(1)*10;             % Block Tonnage            
   Cutoff = app.CutoffgradegtEditField.Value;%4.5%3.5/31.1035; %Para(12)/31.10348;      % Stope Cutoff grade
    %Grade = %%reshape(F,27,65);       % Block Grades
    blkgrd = load (sprintf(app.Label.Text));
     Grade = blkgrd.Grade;                    % Block Grades
%     Grade = zeros(10,10);
%     Grade(3:5,3:8) = 0.5 + rand(3,6)/2;
%     Grade(7:10,7:10) = 0.5 + rand(4,4)/2;


    %Grade = %F(1:18,10:22); % inserted to make a smaller problem for testing
    %Grade = %reshape(Grade,[],1);
    [r,c] = size(Grade);            % Dimensions
 %% Economic Block Value calculation:   Equation 1
%  EBV = (((Price-Cref)*Grade*Rec)-(Cmin+Cpro))*BlkTon;
blkecon = load (sprintf(app.Label_2.Text));
   EBV = blkecon.EBV;

%     EBV = -ones(10,10);
%     EBV(3:5,3:8) = 1;
%     EBV(7:10,7:10) = 1;


%% Geometrical and Geotechnical Parameter Initialization:
     n = r;               % Number of blocks in the x direction in Model
     J = c;                % Number of blocks in the y direction in Model
% [n, J] = size(EBV);
alpha1 = app.MinMiningAlpha1EditField.Value; %Para(14);        % Minimum Mining Width
alpha2 = app.MinMiningAlpha2EditField.Value; %Para(14);        % Minimum Mining Width
gamma1 = app.MinPillarGamma1EditField.Value; %Para(18);%5;     % Minimum Pillar Width
gamma2 = app.MinPillarGamma2EditField.Value; %Para(18);%5;     % Minimum Pillar Width
     S = app.NumberofStopesEditField.Value; %Para(16);        % Number of stopes
 beta1 = app.MaxMiningBeta1EditField.Value; %max mining
 beta2 = app.MaxMiningBeta2EditField.Value;%MAX MINING
%% Mathematical Model:

% Decision Varibales Definition:
  prob = optimproblem('ObjectiveSense','maximize');
     y = optimvar('y',n,J,S,'Type','integer','LowerBound',0,'UpperBound',1);
     z1 = optimvar('z1',n,J,S,'Type','integer','LowerBound',0,'UpperBound',1);
     w1 = optimvar('w1',n,J,'Type','integer','LowerBound',0,'UpperBound',1);

     z2 = optimvar('z2',n,J,S,'Type','integer','LowerBound',0,'UpperBound',1);
     w2 = optimvar('w2',n,J,'Type','integer','LowerBound',0,'UpperBound',1);

 %% Objective function : Equation 2 
prob.Objective = 0;
for k = 1:S
    prob.Objective = prob.Objective + sum(sum(y(:,:,k).*EBV));
end

%% Variable by variable constraints
prob.Constraints.cons1 = optimconstr(n,J,S);
prob.Constraints.cons2 = optimconstr(n,J,S);
prob.Constraints.cons3 = optimconstr(n,J,S);
prob.Constraints.cons4 = optimconstr(n,J,S);
prob.Constraints.cons5 = optimconstr(n,J,S);
prob.Constraints.cons6 = optimconstr(n,J,S);
prob.Constraints.eq6h = optimconstr(n,J,S);
prob.Constraints.eq6v = optimconstr(n,J,S);

for i=1:n
    for j=1:J
        for k = 1:S
            if i > 2
                prob.Constraints.cons1(i,j,k) = y(i,j,k) - y(i-1,j,k) <= z1(i,j,k); 
            end
            
            if j > 2
                prob.Constraints.cons2(i,j,k) = y(i,j,k) - y(i,j-1,k) <= z2(i,j,k);
            end

            if i > 2
                prob.Constraints.cons3(i,j,k) = 1 - y(i-1,j,k) >= z1(i,j,k); 
            end
            
            if j > 2
                prob.Constraints.cons4(i,j,k) = 1 - y(i,j-1,k) >= z2(i,j,k);
            end
            prob.Constraints.eq6h(i,j,k) = z1(i,j,k) <= beta1 - sum(y(min(n,i+1):min(n,i+beta1),j,k));
            prob.Constraints.eq6v(i,j,k) = z2(i,j,k) <= beta2 - sum(y(i,min(j+1,J):min(J,j+beta2),k));
            prob.Constraints.cons5(i,j,k) = sum(z1(max(i-alpha1 + 1,1):i,j,k)) <= y(i,j,k); % Equation 5
            prob.Constraints.cons6(i,j,k) = sum(z2(i,max(j-alpha2 + 1,1):j,k)) <= y(i,j,k); % Equation 6
        end
    end
end


%%
%Constraint:---- Mining Block Control: Equation 8
prob.Constraints.cons7  = sum(y,3) <= 1; %Ensure only 1 block per stope
%%
% %% Stope by stope constraints
prob.Constraints.cons8 = optimconstr(S,1); % Initialize Cut-off grade constraints
%prob.Constraints.cons100h = optimconstr(n,S);
prob.Constraints.cons100v = optimconstr(n,J,S);
for k = 1:S
    prob.Constraints.cons8(k) = sum(sum(Grade.*y(:,:,k))) - Cutoff*sum(sum(y(:,:,k))) >= 0; % Cut-off grade constraints (Equation 9)
    prob.Constraints.cons100v(:,:,k) = beta1 + beta2 >= sum(sum(z1(:,:,k),1)) + sum(sum(z2(:,:,k),2));
end
%% Block by block constraints
prob.Constraints.cons9  = optimconstr(n,J); % Initialize Equation 10
prob.Constraints.cons10 = optimconstr(n,J); % Initialize Equation 11
prob.Constraints.cons11 = optimconstr(n,J); % Initialize Equation 12
prob.Constraints.cons12 = optimconstr(n,J); % Initialize Equation 13
for i=1:n
    for j=1:J
        prob.Constraints.cons9(i,j)  = sum(w1(max(i-gamma1+1,1):i,j)) <=1- sum(y(i,j,:)); % Equation 10
        prob.Constraints.cons10(i,j) = sum(w2(i,max(j-gamma2+1,1):j)) <=1- sum(y(i,j,:)); % Equation 11
        if i > 1; prob.Constraints.cons11(i,j) = sum(y(i,j,:)) - sum(y(i - 1,j,:)) == sum(z1(i,j,:)) - w1(i,j); end % Equation 12
        if j > 1; prob.Constraints.cons12(i,j) = sum(y(i,j,:)) - sum(y(i,j - 1,:)) == sum(z2(i,j,:)) - w2(i,j); end % Equation 13
    end
end 


%% Solve problem
ts = tic;
options             = optimoptions('intlinprog',MaxTime=Inf);
[sol,OFV,~] = solve(prob,'Options',options);

Tm = toc(ts);  %End Time
stp = S;
mm = alpha1;
mp = gamma1;
app.EditField.Value = evaluate(prob.Objective,sol);
app.EditField_2.Value = Tm;
app.EditField_3.Value = 0.00;
% write(exitflag)
% A = fscanf(fileID,formatSpec);
% readfile(WriteOutput.txt)
app.EditField_4.Value = 'optimal solution';


% if xFlag == 1
% app.EditField_4.Value = show(exitflag);
% end
%app.Label_4.Text = tmm;
%app.GapToleranceEditField
%app.Label_5.text = xxFlag;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%% End of Optimization Problem
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
disp('Optimization complete...')

%%
disp('Writing Results to file...')

Fin = sprintf(app.EditField_5.Value) +"_00100.xlsx";
% writematrix(sol.y(:,:,1),Fin,Sheet=1,Range='A2')
% writematrix(MB.z,Fin,Sheet=2,Range='C2')

sht = 5;
for i =1:stp

writematrix(sol.y(:,:,i),Fin,Sheet=sht,Range='N2')
sht= sht + 1;
end

writematrix(Cutoff,Fin,'Sheet',3,'Range','A2',WriteMode='inplace')
writematrix(mm,Fin,'Sheet',3,'Range','B2',WriteMode='inplace')
writematrix(mp,Fin,'Sheet',3,'Range','C2',WriteMode='inplace')
writematrix(stp,Fin,'Sheet',3,'Range','D2',WriteMode='inplace')
writematrix(OFV,Fin,'Sheet',3,'Range','E2',WriteMode='inplace')
writematrix(Tm,Fin,'Sheet',3,'Range','F2',WriteMode='inplace')
%writematrix(xFlag,Fin,'Sheet',3,'Range','I2',WriteMode='inplace')
writematrix(EBV,Fin,'Sheet',3,'Range','H2',WriteMode='inplace')
writematrix(Grade,Fin,'Sheet',2,'Range','H2',WriteMode='inplace')

%%
Finn =  sprintf(app.EditField_5.Value) +"_00200.xlsx";
X = ['a' "b" "c" "d" "e" "f" 'g','h','i','j','k','l','m','n','o','p','q','r','s','t','u','v','w','x','y','z','aa','ab','ac','ad'];
 for i =1:stp
 X(i)+2;
  writematrix(reshape(sol.y(:,:,i)*i,[],1),Finn,'Sheet',1,'Range',X(i)+2,WriteMode='inplace')
  writematrix(reshape(sol.y(:,:,i).*Grade,[],1),Finn,'Sheet',2,'Range',X(i)+2,WriteMode='inplace')
  writematrix(reshape(sol.y(:,:,i).*EBV,[],1),Finn,'Sheet',3,'Range',X(i)+2,WriteMode='inplace')
  writematrix(reshape(sol.y(:,:,i),[],1),Finn,'Sheet',4,'Range',X(i)+2,WriteMode='inplace')
  writematrix(mean(nonzeros(reshape(sol.y(:,:,i).*Grade,[],1))),Finn,'Sheet',5,'Range',X(i)+2,WriteMode='inplace')
  writematrix(reshape(sol.y(:,:,i),[],1),Finn,'Sheet',5,'Range',X(i)+3,WriteMode='inplace')

% gin = 'res000000.xlsx' 
% X = ['a' "b" "c" "d" "e" "f" 'g','h','i','j','k','l','m','n','o','p','q','r','s','t']
%  for i =1:stp
%  X(i)+2
%   writematrix(mean(nonzeros(reshape(sol.y(:,:,i).*Grade,[],1))),gin,'Sheet',1,'Range',X(i)+2,WriteMode='inplace')
%   writematrix(reshape(sol.y(:,:,i),[],1),gin,'Sheet',1,'Range',X(i)+3,WriteMode='inplace')
%  end 
% mean(nonzeros(reshape(sol.y(:,:,1).*Grade,[],1)))

 end 
 if evaluate(prob.Objective,sol) > 0
     app.SSLOLamp.Color = [0 1 1];
 else 
     app.SSLOLamp.Color = [1 0 0];
 end
disp('Writing Results to file completed.......')
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            switch value
                case {5,6,7,8,9,10,11,43,27,18,65,25,30,40,50}
                    app.SSLOLamp.Color = [0 1 1];
                   
                otherwise
                    app.SSLOLamp.Color = [1 0 0];
            end                                                             
            
        end

        % Callback function
        function SelectBILPFileListBoxValueChanged(app, event)
      
        end

        % Callback function
        function BILPFileDropDownOpening(app, event)

        end

        % Button pushed function: BILP_GradeButton
        function BILP_GradeButtonPushed(app, event)
           filein = -1;
            while filein < 0        % repeat until valid file name entered
                    [inputfile, inputpath, ~] = uigetfile({'*.mat';'*.*'},...
                    'Select BILP_Grade input file');  % get GUI file selector
                     filein = fopen([inputpath inputfile],'r');    % read only
                 if filein < 0, disp('! File not found or file sharing problem !'), end
            end
         fclose(filein);
% %             fileb = uigetfile('*.xlsx','Select input file');
          %  v = app.BILP_GradeButton.ButtonPushedFcn;
            app.Label.Text = inputfile; 
%             blkgrd = load (sprintf(app.Label.Text));
%                 bgrade = blkgrd.Grade;

% A1 = 'X';
% A2 = 'Y';
% A3 = 'Z';
% formatSpec = ' %3$s %2$s %1$s';
% str = sprintf(formatSpec,A1,A2,A3)
        end

        % Value changed function: MiningCostEditField
        function MiningCostEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function SSLOResultsPanelSizeChanged(app, event)
           
        end

        % Button down function: SSLOResultsPanel
        function SSLOResultsPanelButtonDown(app, event)
            app.Label_3.Text = ofv;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_3.Value;
            switch value
                case 0
                    app.SSLOLamp.Color = [0 1 0];
                otherwise
                    app.SSLOLamp.Color = [1 0 0];
            end                                                             
            
        end

        % Button pushed function: BILP_EBVButton
        function BILP_EBVButtonPushed(app, event)
             filein = -1;
            while filein < 0        % repeat until valid file name entered
                    [inputfile, inputpath, ~] = uigetfile({'*.mat';'*.*'},...
                    'Select BLIP_EBV input file');  % get GUI file selector
                     filein = fopen([inputpath inputfile],'r');    % read only
                 if filein < 0, disp('! File not found or file sharing problem !'), end
            end
         fclose(filein);
% %             fileb = uigetfile('*.xlsx','Select input file');
          %  v = app.BILP_GradeButton.ButtonPushedFcn;
            app.Label_2.Text = inputfile; 
%             blkecon = load (sprintf(app.Label_2.Text));
%                bev = blkecon.EBV;
             
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            Fin = sprintf(value) +"_00100.xlsx";
            disp(Fin)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'stopes.png');
            app.SublevelStopeLayoutOptimizationSSLOLabel = uilabel(app.UIFigure);
            app.SublevelStopeLayoutOptimizationSSLOLabel.Text = 'Sublevel Stope Layout Optimization (SSLO)';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'play.png');
            app.RunButton.Text = 'Run';
            app.EBVGeneratorPanel = uipanel(app.UIFigure);
            app.EBVGeneratorPanel.Title = 'EBV Generator';
            app.PriceLabel = uilabel(app.EBVGeneratorPanel);
            app.PriceLabel.Text = 'Price ($)';
            app.PriceEditField = uieditfield(app.EBVGeneratorPanel, 'numeric');
            app.PriceEditField.Limits = [0 Inf];
            app.PriceEditField.RoundFractionalValues = 'on';
            app.RefineryCostLabel = uilabel(app.EBVGeneratorPanel);
            app.RefineryCostLabel.Text = 'Refinery Cost ($)';
            app.RefineryCostEditField = uieditfield(app.EBVGeneratorPanel, 'numeric');
            app.RefineryCostEditField.Limits = [0 Inf];
            app.RefineryCostEditField.RoundFractionalValues = 'on';
            app.RecoveryLabel = uilabel(app.EBVGeneratorPanel);
            app.RecoveryLabel.Text = 'Recovery (%)';
            app.RecoveryEditField = uieditfield(app.EBVGeneratorPanel, 'numeric');
            app.RecoveryEditField.Limits = [0 Inf];
            app.CutoffgradegtEditFieldLabel = uilabel(app.EBVGeneratorPanel);
            app.CutoffgradegtEditFieldLabel.Text = 'Cutoff grade (g/t)';
            app.CutoffgradegtEditField = uieditfield(app.EBVGeneratorPanel, 'numeric');
            app.CutoffgradegtEditField.Limits = [0 Inf];
            app.CutoffgradegtEditField.ValueDisplayFormat = '%.2f';
            app.MiningCostEditFieldLabel = uilabel(app.EBVGeneratorPanel);
            app.MiningCostEditFieldLabel.Text = 'Mining Cost ($)';
            app.MiningCostEditField = uieditfield(app.EBVGeneratorPanel, 'numeric');
            app.MiningCostEditField.Limits = [0 Inf];
            app.MiningCostEditField.RoundFractionalValues = 'on';
            app.MiningCostEditField.ValueChangedFcn = createCallbackFcn(app, @MiningCostEditFieldValueChanged, true);
            app.MiningCostEditField.Value = 10;
            app.ProcessingCostLabel = uilabel(app.EBVGeneratorPanel);
            app.ProcessingCostLabel.Text = 'Processing Cost ($)';
            app.ProcessingCostEditField = uieditfield(app.EBVGeneratorPanel, 'numeric');
            app.ProcessingCostEditField.Limits = [0 Inf];
            app.ProcessingCostEditField.RoundFractionalValues = 'on';
            app.StopeConfigurationPanel = uipanel(app.UIFigure);
            app.StopeConfigurationPanel.Title = 'Stope Configuration';
            app.MinMiningAlpha1Label = uilabel(app.StopeConfigurationPanel);
            app.MinMiningAlpha1Label.Text = 'Min Mining (Alpha1) ';
            app.MinMiningAlpha1EditField = uieditfield(app.StopeConfigurationPanel, 'numeric');
            app.MinMiningAlpha1EditField.Limits = [0 Inf];
            app.MinMiningAlpha1EditField.RoundFractionalValues = 'on';
            app.MinMiningAlpha2EditFieldLabel = uilabel(app.StopeConfigurationPanel);
            app.MinMiningAlpha2EditFieldLabel.Text = 'Min Mining (Alpha2) ';
            app.MinMiningAlpha2EditField = uieditfield(app.StopeConfigurationPanel, 'numeric');
            app.MinMiningAlpha2EditField.Limits = [0 Inf];
            app.MinMiningAlpha2EditField.RoundFractionalValues = 'on';
            app.MaxMiningBeta1EditFieldLabel = uilabel(app.StopeConfigurationPanel);
            app.MaxMiningBeta1EditFieldLabel.Text = 'Max Mining (Beta1)';
            app.MaxMiningBeta1EditField = uieditfield(app.StopeConfigurationPanel, 'numeric');
            app.MaxMiningBeta1EditField.Limits = [0 Inf];
            app.MaxMiningBeta1EditField.RoundFractionalValues = 'on';
            app.NumberofStopesEditFieldLabel = uilabel(app.StopeConfigurationPanel);
            app.NumberofStopesEditFieldLabel.Text = 'Number of Stopes';
            app.NumberofStopesEditField = uieditfield(app.StopeConfigurationPanel, 'numeric');
            app.NumberofStopesEditField.Limits = [0 Inf];
            app.NumberofStopesEditField.RoundFractionalValues = 'on';
            app.MaxMiningBeta2EditFieldLabel = uilabel(app.StopeConfigurationPanel);
            app.MaxMiningBeta2EditFieldLabel.Text = 'Max Mining (Beta2)';
            app.MaxMiningBeta2EditField = uieditfield(app.StopeConfigurationPanel, 'numeric');
            app.MaxMiningBeta2EditField.Limits = [0 Inf];
            app.MaxMiningBeta2EditField.RoundFractionalValues = 'on';
            app.MinPillarGamma1EditFieldLabel = uilabel(app.StopeConfigurationPanel);
            app.MinPillarGamma1EditFieldLabel.Text = 'Min Pillar (Gamma1)';
            app.MinPillarGamma1EditField = uieditfield(app.StopeConfigurationPanel, 'numeric');
            app.MinPillarGamma1EditField.Limits = [0 Inf];
            app.MinPillarGamma1EditField.RoundFractionalValues = 'on';
            app.MinPillarGamma2EditFieldLabel = uilabel(app.StopeConfigurationPanel);
            app.MinPillarGamma2EditFieldLabel.Text = 'Min Pillar (Gamma2)';
            app.MinPillarGamma2EditField = uieditfield(app.StopeConfigurationPanel, 'numeric');
            app.MinPillarGamma2EditField.Limits = [0 Inf];
            app.MinPillarGamma2EditField.RoundFractionalValues = 'on';
            app.NumberofBlockPerDirectionPanel = uipanel(app.UIFigure);
            app.NumberofBlockPerDirectionPanel.Title = 'Number of Block Per Direction';
            app.XEditFieldLabel = uilabel(app.NumberofBlockPerDirectionPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.NumberofBlockPerDirectionPanel, 'numeric');
            app.XEditField.Limits = [0 Inf];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.NumberofBlockPerDirectionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.NumberofBlockPerDirectionPanel, 'numeric');
            app.YEditField.Limits = [0 Inf];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.NumberofBlockPerDirectionPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.NumberofBlockPerDirectionPanel, 'numeric');
            app.ZEditField.Limits = [0 Inf];
            app.ZEditField.RoundFractionalValues = 'on';
            app.SSLOLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SelectGradeFileLabel = uilabel(app.UIFigure);
            app.SelectGradeFileLabel.Text = 'Select Grade File';
            app.BILP_GradeButton = uibutton(app.UIFigure, 'push');
            app.BILP_GradeButton.ButtonPushedFcn = createCallbackFcn(app, @BILP_GradeButtonPushed, true);
            app.BILP_GradeButton.Text = 'BILP_Grade';
            app.SSLOResultsPanel = uipanel(app.UIFigure);
            app.SSLOResultsPanel.Title = 'SSLO Results';
            app.SSLOResultsPanel.ButtonDownFcn = createCallbackFcn(app, @SSLOResultsPanelButtonDown, true);
            app.OFVLabel = uilabel(app.SSLOResultsPanel);
            app.OFVLabel.Text = 'OFV';
            app.TimeLabel = uilabel(app.SSLOResultsPanel);
            app.TimeLabel.Text = 'Time';
            app.XflagLabel = uilabel(app.SSLOResultsPanel);
            app.XflagLabel.Text = 'Xflag';
            app.GapLabel = uilabel(app.SSLOResultsPanel);
            app.GapLabel.Text = 'Gap';
            app.EditField = uieditfield(app.SSLOResultsPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.3f';
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.SSLOResultsPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.SSLOResultsPanel, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.2f';
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.SSLOResultsPanel, 'text');
            app.EditField_4.Editable = 'off';
            app.SelectEBVFileLabel = uilabel(app.UIFigure);
            app.SelectEBVFileLabel.Text = 'Select EBV File';
            app.BILP_EBVButton = uibutton(app.UIFigure, 'push');
            app.BILP_EBVButton.ButtonPushedFcn = createCallbackFcn(app, @BILP_EBVButtonPushed, true);
            app.BILP_EBVButton.Text = 'BILP_EBV';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.SaveFileLabel = uilabel(app.UIFigure);
            app.SaveFileLabel.Text = 'Save File';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BILP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end